classdef war_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        GridLayoutA      matlab.ui.container.GridLayout
        Label_Astate     matlab.ui.control.Label
        Label_Ay         matlab.ui.control.Label
        Label_Az         matlab.ui.control.Label
        Label_A          matlab.ui.control.Label
        Spinner_Ay_z     matlab.ui.control.Spinner
        Label_16         matlab.ui.control.Label
        Spinner_Ay_f     matlab.ui.control.Spinner
        Spinner_22Label  matlab.ui.control.Label
        Spinner_Ay_m     matlab.ui.control.Spinner
        Spinner_21Label  matlab.ui.control.Label
        Spinner_Ay_s     matlab.ui.control.Spinner
        Spinner_20Label  matlab.ui.control.Label
        Spinner_Ay_c     matlab.ui.control.Spinner
        Label_19         matlab.ui.control.Label
        Spinner_Ay_h     matlab.ui.control.Spinner
        Label_13         matlab.ui.control.Label
        Spinner_Az_z     matlab.ui.control.Spinner
        Label_12         matlab.ui.control.Label
        Spinner_Az_m     matlab.ui.control.Spinner
        Label_10         matlab.ui.control.Label
        Spinner_Az_s     matlab.ui.control.Spinner
        Label_9          matlab.ui.control.Label
        Spinner_Az_c     matlab.ui.control.Spinner
        Label_8          matlab.ui.control.Label
        Spinner_Az_f     matlab.ui.control.Spinner
        Label_11         matlab.ui.control.Label
        CenterPanel      matlab.ui.container.Panel
        GridLayout2      matlab.ui.container.GridLayout
        GridLayout3      matlab.ui.container.GridLayout
        GridLayout4      matlab.ui.container.GridLayout
        GvsG             matlab.ui.control.Button
        Label_15         matlab.ui.control.Label
        Button_rand      matlab.ui.control.Button
        HvsH             matlab.ui.control.Button
        RvsG             matlab.ui.control.Button
        RvsR             matlab.ui.control.Button
        GridLayout6      matlab.ui.container.GridLayout
        Label_14         matlab.ui.control.Label
        Slider           matlab.ui.control.Slider
        UIAxes           matlab.ui.control.UIAxes
        RightPanel       matlab.ui.container.Panel
        GridLayoutB      matlab.ui.container.GridLayout
        Label_Bstate     matlab.ui.control.Label
        Label_By         matlab.ui.control.Label
        Label_Bz         matlab.ui.control.Label
        Label_B          matlab.ui.control.Label
        Spinner_By_z     matlab.ui.control.Spinner
        Label_18         matlab.ui.control.Label
        Spinner_By_f     matlab.ui.control.Spinner
        Spinner_33Label  matlab.ui.control.Label
        Spinner_By_m     matlab.ui.control.Spinner
        Spinner_32Label  matlab.ui.control.Label
        Spinner_By_s     matlab.ui.control.Spinner
        Spinner_31Label  matlab.ui.control.Label
        Spinner_By_c     matlab.ui.control.Spinner
        Label_21         matlab.ui.control.Label
        Spinner_By_h     matlab.ui.control.Spinner
        km2Label         matlab.ui.control.Label
        Spinner_Bz_z     matlab.ui.control.Spinner
        Label_17         matlab.ui.control.Label
        Spinner_Bz_m     matlab.ui.control.Spinner
        Spinner_27Label  matlab.ui.control.Label
        Spinner_Bz_s     matlab.ui.control.Spinner
        Spinner_26Label  matlab.ui.control.Label
        Spinner_Bz_c     matlab.ui.control.Spinner
        Label_20         matlab.ui.control.Label
        Spinner_Bz_f     matlab.ui.control.Spinner
        Spinner_24Label  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = ['战争模型 语音播报需安装text-to-speech' ...
                ' 人工智能本硕博2101班 赵为之 U202115195'];
        end

        % Value changed function: Slider, Spinner_Ay_c, Spinner_Ay_f, 
        % ...and 20 other components
        function SliderValueChanged(app, event)
            update_fig(app, '甲军', '乙军')
        end

        % Button pushed function: RvsR
        function RvsRButtonPushed(app, event)
            quick_set_RvsR(app, '法军', '英普联军');
        end

        % Button pushed function: RvsG
        function RvsGButtonPushed(app, event)
            quick_set_RvsG(app, '阿富汗抵抗力量', '苏军')
        end

        % Button pushed function: GvsG
        function GvsGButtonPushed(app, event)
            quick_set_GvsG(app, '老挝共产党', '老挝皇家政府')
        end

        % Button pushed function: HvsH
        function HvsHButtonPushed(app, event)
            quick_set_HvsH(app, '叙利亚政府', '叙利亚反对派')
        end

        % Button pushed function: Button_rand
        function Button_randPushed(app, event)
            quick_set_rand(app, '甲军', '乙军')
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {606, 606, 606};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {606, 606};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {291, '1x', 293};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayoutA = uigridlayout(app.LeftPanel);
            app.Label_11 = uilabel(app.GridLayoutA);
            app.Label_11.Text = '非战斗减员率';
            app.Spinner_Az_f = uispinner(app.GridLayoutA);
            app.Spinner_Az_f.Step = 0.1;
            app.Spinner_Az_f.Limits = [0 1];
            app.Spinner_Az_f.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_8 = uilabel(app.GridLayoutA);
            app.Label_8.Text = '初始兵力(人)';
            app.Spinner_Az_c = uispinner(app.GridLayoutA);
            app.Spinner_Az_c.Step = 10;
            app.Spinner_Az_c.Limits = [0 Inf];
            app.Spinner_Az_c.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_9 = uilabel(app.GridLayoutA);
            app.Label_9.Text = '射击率';
            app.Spinner_Az_s = uispinner(app.GridLayoutA);
            app.Spinner_Az_s.Step = 0.1;
            app.Spinner_Az_s.Limits = [0.001 1];
            app.Spinner_Az_s.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Spinner_Az_s.Value = 0.1;
            app.Label_10 = uilabel(app.GridLayoutA);
            app.Label_10.Text = '命中率';
            app.Spinner_Az_m = uispinner(app.GridLayoutA);
            app.Spinner_Az_m.Step = 0.1;
            app.Spinner_Az_m.Limits = [0 1];
            app.Spinner_Az_m.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_12 = uilabel(app.GridLayoutA);
            app.Label_12.Text = '增援率(人/天)';
            app.Spinner_Az_z = uispinner(app.GridLayoutA);
            app.Spinner_Az_z.Limits = [0 Inf];
            app.Spinner_Az_z.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_13 = uilabel(app.GridLayoutA);
            app.Label_13.Text = '活动区面积(km^2)';
            app.Spinner_Ay_h = uispinner(app.GridLayoutA);
            app.Spinner_Ay_h.Step = 50;
            app.Spinner_Ay_h.Limits = [1 Inf];
            app.Spinner_Ay_h.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Spinner_Ay_h.Value = 50;
            app.Label_19 = uilabel(app.GridLayoutA);
            app.Label_19.Text = '初始兵力(人)';
            app.Spinner_Ay_c = uispinner(app.GridLayoutA);
            app.Spinner_Ay_c.Step = 10;
            app.Spinner_Ay_c.Limits = [0 Inf];
            app.Spinner_Ay_c.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Spinner_20Label = uilabel(app.GridLayoutA);
            app.Spinner_20Label.Text = '射击率';
            app.Spinner_Ay_s = uispinner(app.GridLayoutA);
            app.Spinner_Ay_s.Step = 0.1;
            app.Spinner_Ay_s.Limits = [0.001 1];
            app.Spinner_Ay_s.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Spinner_Ay_s.Value = 0.1;
            app.Spinner_21Label = uilabel(app.GridLayoutA);
            app.Spinner_21Label.Text = '命中率';
            app.Spinner_Ay_m = uispinner(app.GridLayoutA);
            app.Spinner_Ay_m.Step = 0.1;
            app.Spinner_Ay_m.Limits = [0 1];
            app.Spinner_Ay_m.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Spinner_22Label = uilabel(app.GridLayoutA);
            app.Spinner_22Label.Text = '非战斗减员率';
            app.Spinner_Ay_f = uispinner(app.GridLayoutA);
            app.Spinner_Ay_f.Step = 0.1;
            app.Spinner_Ay_f.Limits = [0 1];
            app.Spinner_Ay_f.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_16 = uilabel(app.GridLayoutA);
            app.Label_16.Text = '增援率(人/天)';
            app.Spinner_Ay_z = uispinner(app.GridLayoutA);
            app.Spinner_Ay_z.Limits = [0 Inf];
            app.Spinner_Ay_z.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_A = uilabel(app.GridLayoutA);
            app.Label_A.Text = '甲军';
            app.Label_Az = uilabel(app.GridLayoutA);
            app.Label_Az.Text = '正规战';
            app.Label_Ay = uilabel(app.GridLayoutA);
            app.Label_Ay.Text = '游击战';
            app.Label_Astate = uilabel(app.GridLayoutA);
            app.Label_Astate.Text = '';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, '人数-时间变化曲线')
            xlabel(app.UIAxes, '时间')
            ylabel(app.UIAxes, '兵力')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.Slider = uislider(app.GridLayout6);
            app.Slider.Limits = [1 100];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            app.Label_14 = uilabel(app.GridLayout6);
            app.Label_14.Text = '仿真时间(天)';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.RvsR = uibutton(app.GridLayout4, 'push');
            app.RvsR.ButtonPushedFcn = createCallbackFcn(app, @RvsRButtonPushed, true);
            app.RvsR.Tooltip = {'滑铁卢战役是1815年6月18日，由法军对反法联军在比利时小镇滑铁卢进行的决战。'; '战役结局是反法联军获得了决定性胜利。'};
            app.RvsR.Text = {'正规战对正规战'; '滑铁卢战役'};
            app.RvsG = uibutton(app.GridLayout4, 'push');
            app.RvsG.ButtonPushedFcn = createCallbackFcn(app, @RvsGButtonPushed, true);
            app.RvsG.Tooltip = {'苏联-阿富汗战争，是1979年12月末苏联入侵阿富汗导致的长达10年的战争。这场战争被认为是苏联对外政策的重大失败。'};
            app.RvsG.Text = {'正规战对游击战'; '阿富汗战争'};
            app.HvsH = uibutton(app.GridLayout4, 'push');
            app.HvsH.ButtonPushedFcn = createCallbackFcn(app, @HvsHButtonPushed, true);
            app.HvsH.Tooltip = {'叙利亚内战，指从2011年年初持续的叙利亚政府与叙利亚反对派组织、伊斯兰国之间的冲突。'; '这场战争尚没有明确的结果。'};
            app.HvsH.Text = {'混合战'; '叙利亚内战'};
            app.Button_rand = uibutton(app.GridLayout4, 'push');
            app.Button_rand.ButtonPushedFcn = createCallbackFcn(app, @Button_randPushed, true);
            app.Button_rand.Text = '随机设置';
            app.Label_15 = uilabel(app.GridLayout4);
            app.Label_15.Text = '快速设置数值';
            app.GvsG = uibutton(app.GridLayout4, 'push');
            app.GvsG.ButtonPushedFcn = createCallbackFcn(app, @GvsGButtonPushed, true);
            app.GvsG.Tooltip = {'老挝内战发生于1953年至1975年，是左派的老挝共产党巴特寮（Pathet Lao）与右派的老挝皇家政府之间的内战。'; '1975年，北越和老挝人民党取得了战争的胜利，这也是该年中南半岛共产党胜利的一部分。'};
            app.GvsG.Text = {'游击战对游击战'; '老挝内战'};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayoutB = uigridlayout(app.RightPanel);
            app.Spinner_24Label = uilabel(app.GridLayoutB);
            app.Spinner_24Label.Text = '非战斗减员率';
            app.Spinner_Bz_f = uispinner(app.GridLayoutB);
            app.Spinner_Bz_f.Step = 0.1;
            app.Spinner_Bz_f.Limits = [0 1];
            app.Spinner_Bz_f.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_20 = uilabel(app.GridLayoutB);
            app.Label_20.Text = '初始兵力(人)';
            app.Spinner_Bz_c = uispinner(app.GridLayoutB);
            app.Spinner_Bz_c.Step = 10;
            app.Spinner_Bz_c.Limits = [0 Inf];
            app.Spinner_Bz_c.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Spinner_26Label = uilabel(app.GridLayoutB);
            app.Spinner_26Label.Text = '射击率';
            app.Spinner_Bz_s = uispinner(app.GridLayoutB);
            app.Spinner_Bz_s.Step = 0.1;
            app.Spinner_Bz_s.Limits = [0.001 1];
            app.Spinner_Bz_s.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Spinner_Bz_s.Value = 0.1;
            app.Spinner_27Label = uilabel(app.GridLayoutB);
            app.Spinner_27Label.Text = '命中率';
            app.Spinner_Bz_m = uispinner(app.GridLayoutB);
            app.Spinner_Bz_m.Step = 0.1;
            app.Spinner_Bz_m.Limits = [0 1];
            app.Spinner_Bz_m.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_17 = uilabel(app.GridLayoutB);
            app.Label_17.Text = '增援率(人/天)';
            app.Spinner_Bz_z = uispinner(app.GridLayoutB);
            app.Spinner_Bz_z.Limits = [0 Inf];
            app.Spinner_Bz_z.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.km2Label = uilabel(app.GridLayoutB);
            app.km2Label.Text = '活动区面积(km^2)';
            app.Spinner_By_h = uispinner(app.GridLayoutB);
            app.Spinner_By_h.Step = 50;
            app.Spinner_By_h.Limits = [1 Inf];
            app.Spinner_By_h.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Spinner_By_h.Value = 50;
            app.Label_21 = uilabel(app.GridLayoutB);
            app.Label_21.Text = '初始兵力(人)';
            app.Spinner_By_c = uispinner(app.GridLayoutB);
            app.Spinner_By_c.Step = 10;
            app.Spinner_By_c.Limits = [0 Inf];
            app.Spinner_By_c.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Spinner_31Label = uilabel(app.GridLayoutB);
            app.Spinner_31Label.Text = '射击率';
            app.Spinner_By_s = uispinner(app.GridLayoutB);
            app.Spinner_By_s.Step = 0.1;
            app.Spinner_By_s.Limits = [0.001 1];
            app.Spinner_By_s.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Spinner_By_s.Value = 0.1;
            app.Spinner_32Label = uilabel(app.GridLayoutB);
            app.Spinner_32Label.Text = '命中率';
            app.Spinner_By_m = uispinner(app.GridLayoutB);
            app.Spinner_By_m.Step = 0.1;
            app.Spinner_By_m.Limits = [0 1];
            app.Spinner_By_m.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Spinner_33Label = uilabel(app.GridLayoutB);
            app.Spinner_33Label.Text = '非战斗减员率';
            app.Spinner_By_f = uispinner(app.GridLayoutB);
            app.Spinner_By_f.Step = 0.1;
            app.Spinner_By_f.Limits = [0 1];
            app.Spinner_By_f.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_18 = uilabel(app.GridLayoutB);
            app.Label_18.Text = '增援率(人/天)';
            app.Spinner_By_z = uispinner(app.GridLayoutB);
            app.Spinner_By_z.Limits = [0 Inf];
            app.Spinner_By_z.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Label_B = uilabel(app.GridLayoutB);
            app.Label_B.Text = '乙军';
            app.Label_Bz = uilabel(app.GridLayoutB);
            app.Label_Bz.Text = '正规战';
            app.Label_By = uilabel(app.GridLayoutB);
            app.Label_By.Text = '游击战';
            app.Label_Bstate = uilabel(app.GridLayoutB);
            app.Label_Bstate.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = war_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end